classdef experiment_bone_grinding < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        DisconnectMCUButton   matlab.ui.control.Button
        StopMotorButton       matlab.ui.control.Button
        StartMotorButton      matlab.ui.control.Button
        ConnectMCUButton      matlab.ui.control.Button
        MOTORCONTROLLabel     matlab.ui.control.Label
        CAMERACONTROLLabel    matlab.ui.control.Label
        DATACOLLECTIONLabel   matlab.ui.control.Label
        StopRecordingButton   matlab.ui.control.Button
        StopCameraButton      matlab.ui.control.Button
        StartRecordingButton  matlab.ui.control.Button
        StartCameraButton     matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end


    properties (Access = private)
        cam % Camera object
        running = false; % Boolean to control the loop
        mcu_connected = false;
        mcu;
        sensor_read; % Name of the MATLAB ROS 2 Node
        result_1;
        result_2;
        result_3;
        status_pid_1;
        status_pid_2;
        status_pid_3;
        subs_1;
        subs_2;
        subs_pid_1;
        subs_pid_2;
        subs_pid_3;
        status_bash_1;
        status_bash_2;
        status_kill_1;
        status_kill_2;
        status_kill_3;
        cmdout_1;
        cmdout_2;
        cmdout_3;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartCameraButton
        function StartCameraButtonPushed(app, event)
            try
                app.cam = webcam(3);
                app.running = true;

                % Get the size of the UIAxes
                % uiAxesPos = app.UIAxes.Position;
                % width = round(uiAxesPos(3));
                % height = round(uiAxesPos(4));

                while app.running
                    img = snapshot(app.cam);

                    % Resize the image to match the size of UIAxes
                    % resizedImg = imresize(img, [height, width]);

                    % Show the resized image inside the UIAxes
                    % imshow(resizedImg, "Parent", app.UIAxes);

                    imshow(img, "Parent", app.UIAxes);

                    pause(0.017); % Reduce CPU load
                end
            catch e
                uialert(app.UIFigure, e.message, 'Camera Error');
            end
        end

        % Button pushed function: StopCameraButton
        function StopCameraButtonPushed(app, event)
            app.running = false;
            if ~isempty(app.cam)
                clear app.cam;
            end
        end

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
            launch_file_1 = 'gnome-terminal -- bash -c "bash ~/experiment_grinding_nose/sensors_experiment/launch_ati.sh; exec bash"';
            launch_file_2 = 'gnome-terminal -- bash -c "bash ~/experiment_grinding_nose/sensors_experiment/run_aurora.sh; exec bash"';
            [app.status_bash_1, ~] = system(launch_file_1);
            [app.status_bash_2, ~] = system(launch_file_2);

            % Set up ROS 2 node
            app.sensor_read = ros2node('/sensors_read');

            % Topic names
            topic_name_1 = '/force_torque_sensor_broadcaster/wrench';
            topic_name_2 = '/aurora_transform';

            pause(5); % Allow time for the topics to appear in the network

            % Declare the sensors_read node as a subscriber of 2 topics
            app.subs_1 = ros2subscriber(app.sensor_read, topic_name_1, @callback_1);
            app.subs_2 = ros2subscriber(app.sensor_read, topic_name_2, @callback_2);

            % Collect data and save this into separate CSV files.
            data_collection;

        end

        % Button pushed function: StopRecordingButton
        function StopRecordingButtonPushed(app, event)
            [app.status_pid_1, app.result_1] = system('pgrep robot_state_pub');
            [app.status_pid_2, app.result_2] = system('pgrep ros2_control_no');
            [app.status_pid_3, app.result_3] = system('pgrep aurora_talker');

            app.subs_pid_1 = str2double(strtrim(app.result_1));
            app.subs_pid_2 = str2double(strtrim(app.result_2));
            app.subs_pid_3 = str2double(strtrim(app.result_3));

            kill_command_1 = sprintf('kill -SIGINT %d', app.subs_pid_1);
            kill_command_2 = sprintf('kill -SIGINT %d', app.subs_pid_2);
            kill_command_3 = sprintf('kill -SIGINT %d', app.subs_pid_3);
            [app.status_kill_1, app.cmdout_1] = system(kill_command_1);
            [app.status_kill_2, app.cmdout_2] = system(kill_command_2);
            [app.status_kill_3, app.cmdout_3] = system(kill_command_3);

            clear("sensors_read");
        end

        % Callback function
        function ExportDataButtonPushed(app, event)
            % data_collection;
        end

        % Callback function
        function ClearWorkspaceButtonPushed(app, event)
            clear;
        end

        % Button pushed function: ConnectMCUButton
        function ConnectMCUButtonPushed(app, event)
            % Function to set serial communication with microcontroller
            port = "/dev/ttyACM0";
            baudrate = 115200;
            app.mcu = serialport(port, baudrate);

            % Configure optional parameter
            configureTerminator(app.mcu, "CR/LF");
            app.mcu.Timeout = 5; % Timeout in seconds

            app.mcu_connected = true;
            disp("STM32 F446RE connected to MATLAB. Ready to receive commands...");
        end

        % Button pushed function: DisconnectMCUButton
        function DisconnectMCUButtonPushed(app, event)
            app.mcu_connected = false;
            clear app.mcu;
        end

        % Button pushed function: StartMotorButton
        function StartMotorButtonPushed(app, event)
            if app.mcu_connected
                motor_on = uint8(0x01);
                write(app.mcu, motor_on, "uint8");
                pause(1);
                flush(app.mcu);
            else
                disp("STM32 F446RE not connected");
            end
        end

        % Button pushed function: StopMotorButton
        function StopMotorButtonPushed(app, event)
            if app.mcu_connected
                motor_off = uint8(0x00);
                write(app.mcu, motor_off, "uint8");
                pause(1);
                flush(app.mcu);
            else
                disp("STM32 F446RE not connected");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Endoscope Preview')
            app.StartCameraButton = uibutton(app.UIFigure, 'push');
            app.StartCameraButton.ButtonPushedFcn = createCallbackFcn(app, @StartCameraButtonPushed, true);
            app.StartCameraButton.Text = 'Start Camera';
            app.StartRecordingButton = uibutton(app.UIFigure, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.StopCameraButton = uibutton(app.UIFigure, 'push');
            app.StopCameraButton.ButtonPushedFcn = createCallbackFcn(app, @StopCameraButtonPushed, true);
            app.StopCameraButton.Text = 'Stop Camera';
            app.StopRecordingButton = uibutton(app.UIFigure, 'push');
            app.StopRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StopRecordingButtonPushed, true);
            app.StopRecordingButton.Text = 'Stop Recording';
            app.DATACOLLECTIONLabel = uilabel(app.UIFigure);
            app.DATACOLLECTIONLabel.Text = 'DATA COLLECTION';
            app.CAMERACONTROLLabel = uilabel(app.UIFigure);
            app.CAMERACONTROLLabel.Text = 'CAMERA CONTROL';
            app.MOTORCONTROLLabel = uilabel(app.UIFigure);
            app.MOTORCONTROLLabel.Text = 'MOTOR CONTROL';
            app.ConnectMCUButton = uibutton(app.UIFigure, 'push');
            app.ConnectMCUButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectMCUButtonPushed, true);
            app.ConnectMCUButton.Text = 'Connect MCU';
            app.StartMotorButton = uibutton(app.UIFigure, 'push');
            app.StartMotorButton.ButtonPushedFcn = createCallbackFcn(app, @StartMotorButtonPushed, true);
            app.StartMotorButton.Text = 'Start Motor';
            app.StopMotorButton = uibutton(app.UIFigure, 'push');
            app.StopMotorButton.ButtonPushedFcn = createCallbackFcn(app, @StopMotorButtonPushed, true);
            app.StopMotorButton.Text = 'Stop Motor';
            app.DisconnectMCUButton = uibutton(app.UIFigure, 'push');
            app.DisconnectMCUButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectMCUButtonPushed, true);
            app.DisconnectMCUButton.Text = 'Disconnect MCU';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = experiment_bone_grinding

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end