classdef experiment_bone_grinding_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        MOTORCONTROLLabel     matlab.ui.control.Label
        CONNECTButton         matlab.ui.control.Button
        RESETButton           matlab.ui.control.Button
        MotorLamp             matlab.ui.control.Lamp
        MotorLampLabel        matlab.ui.control.Label
        FootPedalLamp         matlab.ui.control.Lamp
        FootPedalLampLabel    matlab.ui.control.Label
        CAMERACONTROLLabel    matlab.ui.control.Label
        DATACOLLECTIONLabel   matlab.ui.control.Label
        StopRecordingButton   matlab.ui.control.Button
        StopCameraButton      matlab.ui.control.Button
        StartRecordingButton  matlab.ui.control.Button
        StartCameraButton     matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end


    properties (Access = private)
        cam % Camera object
        running = false; % Boolean to control the loop
        footPedal;
        stm32;
        readTimer;
        isFootPedalConnected = false;
        isStm32Connected = false;
        sensor_read; % Name of the MATLAB ROS 2 Node
        result_1;
        result_2;
        result_3;
        status_pid_1;
        status_pid_2;
        status_pid_3;
        subs_1;
        subs_2;
        subs_pid_1;
        subs_pid_2;
        subs_pid_3;
        status_bash_1;
        status_bash_2;
        status_kill_1;
        status_kill_2;
        status_kill_3;
        cmdout_1;
        cmdout_2;
        cmdout_3;
    end

    methods (Access = private)

        function checkFootPedal(app)
            if app.footPedal.NumBytesAvailable > 0
                footPedal_byte = read(app.footPedal, 1, "uint8");

                if app.isFootPedalConnected
                    if footPedal_byte == 1
                        app.FootPedalLamp.Color = 'green';
                        motor_on = uint8(0x01);
                        if app.isStm32Connected
                            write(app.stm32, motor_on, "uint8");
                            flush(app.stm32);
                            app.MotorLamp.Color = 'green';
                        end
                    elseif footPedal_byte == 0
                        app.FootPedalLamp.Color = 'red';
                        motor_off = uint8(0x00);
                        if app.isStm32Connected
                            write(app.stm32, motor_off, "uint8");
                            flush(app.stm32);
                            app.MotorLamp.Color = 'red';
                        end
                        % pause(2);
                        app.MotorLamp.Color = "#EDB120";
                        app.FootPedalLamp.Color = "#EDB120";
                    end
                end

                % Check if timer is stilll running and stop it if necessary
                if ~app.isFootPedalConnected || ~app.isStm32Connected
                    if ~isempty(app.readTimer) && isvalid(app.readTimer)
                        stop(app.readTimer);  % Stop the timer if either port is disconnected
                        delete(app.readTimer);  % Delete the timer object
                        app.readTimer = [];  % Clear the timer property
                        disp('Timer stopped due to disconnection');
                    end

                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FootPedalLamp.Color = "white";
            app.MotorLamp.Color = "white";
        end

        % Button pushed function: StartCameraButton
        function StartCameraButtonPushed(app, event)
            try
                app.cam = webcam(3);
                app.running = true;

                % Get the size of the UIAxes
                % uiAxesPos = app.UIAxes.Position;
                % width = round(uiAxesPos(3));
                % height = round(uiAxesPos(4));

                while app.running
                    img = snapshot(app.cam);

                    % Resize the image to match the size of UIAxes
                    % resizedImg = imresize(img, [height, width]);

                    % Show the resized image inside the UIAxes
                    % imshow(resizedImg, "Parent", app.UIAxes);

                    imshow(img, "Parent", app.UIAxes);

                    pause(0.017); % Reduce CPU load
                end
            catch e
                uialert(app.UIFigure, e.message, 'Camera Error');
            end
        end

        % Button pushed function: StopCameraButton
        function StopCameraButtonPushed(app, event)
            app.running = false;
            if ~isempty(app.cam)
                clear app.cam;
            end
        end

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
            launch_file_1 = 'gnome-terminal -- bash -c "bash ~/experiment_grinding_nose/sensors_experiment/launch_ati.sh; exec bash"';
            launch_file_2 = 'gnome-terminal -- bash -c "bash ~/experiment_grinding_nose/sensors_experiment/run_aurora.sh; exec bash"';
            [app.status_bash_1, ~] = system(launch_file_1);
            [app.status_bash_2, ~] = system(launch_file_2);

            % Set up ROS 2 node
            app.sensor_read = ros2node('/sensors_read');

            % Topic names
            topic_name_1 = '/force_torque_sensor_broadcaster/wrench';
            topic_name_2 = '/aurora_transform';

            pause(5); % Allow time for the topics to appear in the network

            % Declare the sensors_read node as a subscriber of 2 topics
            app.subs_1 = ros2subscriber(app.sensor_read, topic_name_1, @callback_1);
            app.subs_2 = ros2subscriber(app.sensor_read, topic_name_2, @callback_2);

            % Collect data and save this into separate CSV files.
            data_collection;

        end

        % Button pushed function: StopRecordingButton
        function StopRecordingButtonPushed(app, event)
            [app.status_pid_1, app.result_1] = system('pgrep robot_state_pub');
            [app.status_pid_2, app.result_2] = system('pgrep ros2_control_no');
            [app.status_pid_3, app.result_3] = system('pgrep aurora_talker');

            app.subs_pid_1 = str2double(strtrim(app.result_1));
            app.subs_pid_2 = str2double(strtrim(app.result_2));
            app.subs_pid_3 = str2double(strtrim(app.result_3));

            kill_command_1 = sprintf('kill -SIGINT %d', app.subs_pid_1);
            kill_command_2 = sprintf('kill -SIGINT %d', app.subs_pid_2);
            kill_command_3 = sprintf('kill -SIGINT %d', app.subs_pid_3);
            [app.status_kill_1, app.cmdout_1] = system(kill_command_1);
            [app.status_kill_2, app.cmdout_2] = system(kill_command_2);
            [app.status_kill_3, app.cmdout_3] = system(kill_command_3);

            clear("sensors_read");
        end

        % Button pushed function: CONNECTButton
        function CONNECTButtonPushed(app, event)
            % First initialise the serial ports
            if ~app.isFootPedalConnected
                footPedal_port = "/dev/ttyACM1";
                footPedal_baudrate = 9600;

                % Check if the port is in use before opening it
                portlist = serialportlist("available");
                if any(strcmp(portlist, footPedal_port))
                    disp("Foot pedal already in use. Closing and reopening...");
                    clear app.footPedal;  % Close the open port
                    pause(1);  % Delete the object
                end

                app.footPedal = serialport(footPedal_port, footPedal_baudrate);
                % Configure optional parameters for both parts
                configureTerminator(app.footPedal, "CR/LF");
                app.footPedal.Timeout = 5;
                app.isFootPedalConnected = true;
                disp("Foot Pedal (Adafruit Trinket M0) connected...");
            end

            if ~app.isStm32Connected
                stm32_port = "/dev/ttyACM0";
                stm32_baudrate = 115200;

                portlist = serialportlist("available");
                if any(strcmp(portlist, footPedal_port))
                    disp("STM32 already in use. Closing and Reopening...");
                    clear app.stm32;
                    pause(1);
                end

                app.stm32 = serialport(stm32_port, stm32_baudrate);
                configureTerminator(app.stm32, "CR/LF");
                app.stm32.Timeout = 5;
                app.isStm32Connected = true;
                disp("STM32 F446RE connected...");
            end

            app.FootPedalLamp.Color = "#EDB120";
            app.MotorLamp.Color = "#EDB120";

            app.readTimer = timer( ...
                'ExecutionMode', 'fixedSpacing', ...
                'Period', 0.001, ...
                'TimerFcn', @(~,~)checkFootPedal(app));
            start(app.readTimer);

            % while true
            %     if app.footPedal.NumBytesAvailable > 0
            %         if app.isFootPedalConnected == true
            %             footPedal_byte = read(app.footPedal, 1, "uint8");
            %             if app.isStm32Connected == true
            %                 if footPedal_byte == 1
            %                     app.FootPedalLamp.Color = 'green';
            %                     motor_on = uint8(0x01);
            %                     write(app.stm32, motor_on, "uint8");
            %                     flush(app.stm32);
            %                     app.MotorLamp.Color = 'green';
            %                 elseif footPedal_byte == 0
            %                     app.FootPedalLamp.Color = 'red';
            %                     motor_off = uint8(0x00);
            %                     write(app.stm32, motor_off, "uint8");
            %                     flush(app.stm32);
            %                     app.MotorLamp.Color = 'red';
            %                 end
            %                 % else
            %                 %     app.MotorLamp.Value = false;
            %             end
            %             % else
            %             %     app.FootPedalLamp.Value = false;
            %         end
            %     end
            % end
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            clear all;
            clc;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Endoscope Preview')
            app.StartCameraButton = uibutton(app.UIFigure, 'push');
            app.StartCameraButton.ButtonPushedFcn = createCallbackFcn(app, @StartCameraButtonPushed, true);
            app.StartCameraButton.Text = 'Start Camera';
            app.StartRecordingButton = uibutton(app.UIFigure, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.StopCameraButton = uibutton(app.UIFigure, 'push');
            app.StopCameraButton.ButtonPushedFcn = createCallbackFcn(app, @StopCameraButtonPushed, true);
            app.StopCameraButton.Text = 'Stop Camera';
            app.StopRecordingButton = uibutton(app.UIFigure, 'push');
            app.StopRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StopRecordingButtonPushed, true);
            app.StopRecordingButton.Text = 'Stop Recording';
            app.DATACOLLECTIONLabel = uilabel(app.UIFigure);
            app.DATACOLLECTIONLabel.Text = 'DATA COLLECTION';
            app.CAMERACONTROLLabel = uilabel(app.UIFigure);
            app.CAMERACONTROLLabel.Text = 'CAMERA CONTROL';
            app.FootPedalLampLabel = uilabel(app.UIFigure);
            app.FootPedalLampLabel.Text = 'Foot Pedal';
            app.FootPedalLamp = uilamp(app.UIFigure);
            app.MotorLampLabel = uilabel(app.UIFigure);
            app.MotorLampLabel.Text = 'Motor ';
            app.MotorLamp = uilamp(app.UIFigure);
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.CONNECTButton = uibutton(app.UIFigure, 'push');
            app.CONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @CONNECTButtonPushed, true);
            app.CONNECTButton.Text = 'CONNECT';
            app.MOTORCONTROLLabel = uilabel(app.UIFigure);
            app.MOTORCONTROLLabel.Text = 'MOTOR CONTROL';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = experiment_bone_grinding_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end